classdef Arduino_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        SaveButton   matlab.ui.control.Button
        PauseButton  matlab.ui.control.Button
        StopButton   matlab.ui.control.Button
        StartButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
    end
    
    properties (Access = private)
        serialObj % Description
        recordedData = zeros(1000,1);
        recordedTime = zeros(1000,1);
    end
    
    methods (Access = public)
        
     
        function readSineWaveData(app,src,~)
          
            % Read the ASCII data from the serialport object.
            data = readline(src);
            
            % Convert JSON format into matlab structure
            decodedJson = jsondecode(data);
        
            % Shift signal value data into the recordedData property,
            % This is a circular buffer of length 1000
            app.recordedData = circshift(app.recordedData, 1);
            app.recordedData(1) = decodedJson.signalValue;
            
            app.recordedTime = circshift(app.recordedTime, 1);
            app.recordedTime(1) = decodedJson.time;
        
            
            % Plot the data as a function of time
            plot(app.UIAxes, app.recordedTime, app.recordedData)
            
        end     
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            function startupFcn(app, a)
%             app.arduinoObj = arduino(app.port,'uno');
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
%             app.serialObj = serialport('COM4', 9600);
            app.serialObj = serialport('/dev/cu.usbmodem14301', 9600);
            
            flush(app.serialObj);
            
            configureCallback(app.serialObj, "terminator",  @app.readSineWaveData);
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            configureCallback(app.serialObj, "off");
            close(app.UIFigure)
            clear all
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Visualize Live Incoming Data')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Voltage')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.Text = 'Pause';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Arduino_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end